classdef conectividad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Conectividad                matlab.ui.Figure
        UIAxes_graph                matlab.ui.control.UIAxes
        AB_int_Slider               matlab.ui.control.Slider
        FromAtoBLabel               matlab.ui.control.Label
        BA_int_Slider               matlab.ui.control.Slider
        FromBtoALabel               matlab.ui.control.Label
        AC_int_Slider               matlab.ui.control.Slider
        FromAtoCLabel               matlab.ui.control.Label
        CA_int_Slider               matlab.ui.control.Slider
        FromCtoALabel               matlab.ui.control.Label
        BC_int_Slider               matlab.ui.control.Slider
        FromBtoCLabel               matlab.ui.control.Label
        CB_int_Slider               matlab.ui.control.Slider
        FromCtoBLabel               matlab.ui.control.Label
        IntensityauLabel            matlab.ui.control.Label
        DelaysamplesLabel           matlab.ui.control.Label
        UIAxes_signal               matlab.ui.control.UIAxes
        NoiseA                      matlab.ui.control.Slider
        FreqA                       matlab.ui.control.Slider
        ALabel                      matlab.ui.control.Label
        NoiseB                      matlab.ui.control.Slider
        FreqB                       matlab.ui.control.Slider
        BLabel                      matlab.ui.control.Label
        NoiseC                      matlab.ui.control.Slider
        FreqC                       matlab.ui.control.Slider
        CLabel                      matlab.ui.control.Label
        FrequencyHzLabel            matlab.ui.control.Label
        VariabilityLabel            matlab.ui.control.Label
        PhaseA                      matlab.ui.control.Slider
        PhaseLabel                  matlab.ui.control.Label
        PhaseB                      matlab.ui.control.Slider
        PhaseC                      matlab.ui.control.Slider
        UIAxes_PS                   matlab.ui.control.UIAxes
        ConnectivitymethodLabel     matlab.ui.control.Label
        ConnectivitymethodDropDown  matlab.ui.control.DropDown
        UIAxes_conn_1               matlab.ui.control.UIAxes
        UIAxes_conn_2               matlab.ui.control.UIAxes
        UIAxes_conn_3               matlab.ui.control.UIAxes
        AB_del_Spinner              matlab.ui.control.Spinner
        BA_del_Spinner              matlab.ui.control.Spinner
        AC_del_Spinner              matlab.ui.control.Spinner
        CA_del_Spinner              matlab.ui.control.Spinner
        BC_del_Spinner              matlab.ui.control.Spinner
        CB_del_Spinner              matlab.ui.control.Spinner
        LOADButton                  matlab.ui.control.Button
        SAVEButton                  matlab.ui.control.Button
        RESTARTButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        flag_load % Description
    end
    
    properties (Access = public)
        signals % Description
    end
    
    methods (Access = private)
        
        function plot_conectivity(app)
            %conn=[0 1 0;1 0 2;0 0 0.1];
            
            %connectivity matrix
            conn=zeros(3);
            conn(1,2)=app.AB_int_Slider.Value;
            conn(2,1)=app.BA_int_Slider.Value;
            conn(1,3)=app.AC_int_Slider.Value;
            conn(3,1)=app.CA_int_Slider.Value;
            conn(2,3)=app.BC_int_Slider.Value;
            conn(3,2)=app.CB_int_Slider.Value;
            
            %labels
            clear labels
            labels{1}='A';
            labels{2}='B';
            labels{3}='C';
            
            %Plot
            G=digraph(conn);
            weight_line=5*G.Edges.Weight/max(G.Edges.Weight);
            if isempty(weight_line)
                plot(app.UIAxes_graph, G,'NodeLabel',labels,...
                'XData',[2 1 3],'YData',[2 1 1]);
            else
                p=plot(app.UIAxes_graph, G,'NodeLabel',labels,'LineWidth',weight_line',...
                'XData',[1.8 1 3],'YData',[2 0.8 1.2]);
                p.ArrowSize=15;
            end
            %plot(app.UIAxes_graph,[0 0],[1 2])
            axis(app.UIAxes_graph,'off');
            title(app.UIAxes_graph,'Connectivity diagram')
            %set(gcf,'WindowButtonDownFcn',@(f,~)edit_graph(f,app.UIAxes_graph));
        end
        
        function generate_signals(app)
            %Each signal starts from a sinusoid at a rangivendom phase
            xA_sin=sin(2*pi*app.FreqA.Value*(0:0.001:10.1) + app.PhaseA.Value*(2*pi)/360);
            xB_sin=sin(2*pi*app.FreqB.Value*(0:0.001:10.1) + app.PhaseB.Value*(2*pi)/360);
            xC_sin=sin(2*pi*app.FreqC.Value*(0:0.001:10.1) + app.PhaseC.Value*(2*pi)/360);
            
%             %Include noise
%             xA_sin = xA_sin + (app.NoiseA.Value/10)*(rand(1,length(xA_sin))-0.5);
%             xB_sin = xB_sin + (app.NoiseB.Value/10)*(rand(1,length(xB_sin))-0.5);
%             xC_sin = xC_sin + (app.NoiseC.Value/10)*(rand(1,length(xC_sin))-0.5);

            % Include filtered noise Freq +- 2.5Hz
            fA(1) = app.FreqA.Value - 1; fA(2) = app.FreqA.Value + 1;
            fB(1) = app.FreqB.Value - 1; fB(2) = app.FreqB.Value + 1;
            fC(1) = app.FreqC.Value - 1; fC(2) = app.FreqC.Value + 1;
            fA(fA<0)=0; fB(fB<0)=0; fC(fC<0)=0;
            sigA_fil = eegfilt(10*(rand(1,length(xA_sin))-0.5),1000,fA(1),fA(2));
            sigB_fil = eegfilt(10*(rand(1,length(xB_sin))-0.5),1000,fB(1),fB(2));
            sigC_fil = eegfilt(10*(rand(1,length(xC_sin))-0.5),1000,fC(1),fC(2));
            
            xA_sin = xA_sin + sigA_fil;
            xB_sin = xB_sin + sigB_fil;
            xC_sin = xC_sin + sigC_fil;
            
            %Include relationships
            xA=xA_sin; xB=xB_sin; xC=xC_sin;
            for d=101:10101
%                 xA(d) = xA_sin(d) + ... 
%                         (app.BA_int_Slider.Value/50)*xB_sin(d-app.BA_del_Spinner.Value) + ...
%                         (app.CA_int_Slider.Value/50)*xC_sin(d-app.CA_del_Spinner.Value);
%                 xB(d) = xB_sin(d) + ... 
%                         (app.AB_int_Slider.Value/50)*xA_sin(d-app.AB_del_Spinner.Value) + ...
%                         (app.CB_int_Slider.Value/50)*xC_sin(d-app.CB_del_Spinner.Value);
%                 xC(d) = xC_sin(d) + ... 
%                         (app.AC_int_Slider.Value/50)*xA_sin(d-app.AC_del_Spinner.Value) + ...
%                         (app.BC_int_Slider.Value/50)*xB_sin(d-app.BC_del_Spinner.Value); 

                xA_aux = xA_sin(d) + ... 
                        (app.BA_int_Slider.Value/50)*xB(d-app.BA_del_Spinner.Value) + ...
                        (app.CA_int_Slider.Value/50)*xC(d-app.CA_del_Spinner.Value);
                xB_aux = xB_sin(d) + ... 
                        (app.AB_int_Slider.Value/50)*xA(d-app.AB_del_Spinner.Value) + ...
                        (app.CB_int_Slider.Value/50)*xC(d-app.CB_del_Spinner.Value);
                xC_aux = xC_sin(d) + ... 
                        (app.AC_int_Slider.Value/50)*xA(d-app.AC_del_Spinner.Value) + ...
                        (app.BC_int_Slider.Value/50)*xB(d-app.BC_del_Spinner.Value);  
                xA(d) = xA_aux; xB(d) = xB_aux; xC(d) = xC_aux;    
            end
            xA=xA(101:10101);
            xB=xB(101:10101);
            xC=xC(101:10101);
            
            % Include noise
            xA = xA + (app.NoiseA.Value/10)*(rand(1,length(xA))-0.5);
            xB = xB + (app.NoiseB.Value/10)*(rand(1,length(xB))-0.5);
            xC = xC + (app.NoiseC.Value/10)*(rand(1,length(xC))-0.5);
            
            app.signals.xA=xA;
            app.signals.xB=xB;
            app.signals.xC=xC;
            plot_signals(app);
            plot_spectrum(app);
            compute_connectivity(app);
        end
    
        function plot_signals(app)
            
            if app.flag_load==0
                mA = max(abs(app.signals.xA(1:1000)));
                mB = max(abs(app.signals.xB(1:1000)));
                mC = max(abs(app.signals.xC(1:1000)));
                xA_plot = app.signals.xA(1:1000)/max([mA mB mC]);
                xB_plot = app.signals.xB(1:1000)/max([mA mB mC]);
                xC_plot = app.signals.xC(1:1000)/max([mA mB mC]);
            else
                xA_plot = app.signals.xA*2/(max(app.signals.xA)-min(app.signals.xA));
                xA_plot = xA_plot + (-1) - min(xA_plot);
                xB_plot = app.signals.xB*2/(max(app.signals.xB)-min(app.signals.xB));
                xB_plot = xB_plot + (-1) - min(xB_plot); 
                if app.signals.xC~=0
                    xC_plot = app.signals.xC*2/(max(app.signals.xC)-min(app.signals.xC));
                    xC_plot = xC_plot + (-1) - min(xC_plot);
                end
            end
            
            ejex=linspace(0,1,length(xA_plot));
            
            hold(app.UIAxes_signal, 'off')
            plot(app.UIAxes_signal, ejex, xA_plot)
            hold(app.UIAxes_signal, 'on')
            plot(app.UIAxes_signal, ejex, xB_plot-3)
            plot(app.UIAxes_signal, ejex, xC_plot-6)
            
            axis(app.UIAxes_signal,'off');
            xlim(app.UIAxes_signal,[0 1]);
            ylim(app.UIAxes_signal,[-7 1]);
            title(app.UIAxes_signal,'Signals')
            
        end
        
        function plot_spectrum(app)
            [PSA,~] = compute_PS(app.signals.xA,length(app.signals.xA),0,1000);
            [PSB,~] = compute_PS(app.signals.xB,length(app.signals.xB),0,1000);
            [PSC,f] = compute_PS(app.signals.xC,length(app.signals.xC),0,1000);
            
            hold(app.UIAxes_PS, 'off')
            plot(app.UIAxes_PS, f, PSA)
            hold(app.UIAxes_PS, 'on')
            plot(app.UIAxes_PS, f, PSB)
            plot(app.UIAxes_PS, f, PSC)
            
            xlim(app.UIAxes_PS,[0 100]);
            %ylim(app.UIAxes_PS,[-25 5]);
            title(app.UIAxes_PS,'Power Spectrum')
            xlabel(app.UIAxes_PS,'Frequency (Hz)')
            ylabel(app.UIAxes_PS,[])
        end
        
        function compute_connectivity(app)
            if strcmp(app.ConnectivitymethodDropDown.Value,'-')
                cla(app.UIAxes_conn_1,'reset');
                cla(app.UIAxes_conn_2,'reset');
                cla(app.UIAxes_conn_3,'reset');
            end 
            if strcmp(app.ConnectivitymethodDropDown.Value,'Correlation')
                compute_correlation(app);
            end
            if strcmp(app.ConnectivitymethodDropDown.Value,'Cross-Correlation')
                compute_xcorrelation(app);
            end
            if strcmp(app.ConnectivitymethodDropDown.Value,'Coherence')
                compute_cohere(app);
            end
            if strcmp(app.ConnectivitymethodDropDown.Value,'Granger Causality')
                compute_granger(app);
            end
            if strcmp(app.ConnectivitymethodDropDown.Value,'Partial Granger Causality')
                compute_granger(app);
            end
            if strcmp(app.ConnectivitymethodDropDown.Value,'Spectral Granger Causality')
                compute_spectral_granger(app);
            end
%             if strcmp(app.ConnectivitymethodDropDown.Value,'Partial Spectral GC')
%                 compute_spectral_granger(app);
%             end
        end
        
        function compute_correlation(app)
%             xA = app.signals.xA(1:1000);
%             xB = app.signals.xB(1:1000);
%             xC = app.signals.xC(1:1000);
            xA = app.signals.xA;
            xB = app.signals.xB;
            xC = app.signals.xC;
            % Correlation
            cor_AB = corrcoef(xA,xB); cor_AB = cor_AB(2);
            cor_AC = corrcoef(xA,xC); cor_AC = cor_AC(2);
            cor_BC = corrcoef(xB,xC); cor_BC = cor_BC(2);
            % Partial Correlation
            pcor_AB = partialcorr(xA',xB',xC');
            pcor_AC = partialcorr(xA',xC',xB');
            pcor_BC = partialcorr(xB',xC',xA');
            
            cla(app.UIAxes_conn_1,'reset')
            bar(app.UIAxes_conn_1, 1, cor_AB,'FaceColor',[0 0 0])
            hold(app.UIAxes_conn_1,'on')
            bar(app.UIAxes_conn_1, 2, pcor_AB,'FaceColor',[0.6 0.6 0.6])
            plot(app.UIAxes_conn_1,[0 3],[0 0],'Color',[0.5 0.5 0.5])
            xlim(app.UIAxes_conn_1,[-0.5 3.5]);
            ylim(app.UIAxes_conn_1,[-1 1]);
            xticks(app.UIAxes_conn_1,[1 2])
            xticklabels(app.UIAxes_conn_1,{'Corr'; 'Par-Corr'})
            title(app.UIAxes_conn_1,'Correlation A-B')
            xlabel(app.UIAxes_conn_1,[])
            ylabel(app.UIAxes_conn_1,[])
            
            cla(app.UIAxes_conn_2,'reset')
            bar(app.UIAxes_conn_2, 1, cor_AC,'FaceColor',[0 0 0])
            hold(app.UIAxes_conn_2,'on')
            bar(app.UIAxes_conn_2, 2, pcor_AC,'FaceColor',[0.6 0.6 0.6])
            plot(app.UIAxes_conn_2,[0 3],[0 0],'Color',[0.5 0.5 0.5])
            xlim(app.UIAxes_conn_2,[-0.5 3.5]);
            ylim(app.UIAxes_conn_2,[-1 1]);
            xticks(app.UIAxes_conn_2,[1 2])
            xticklabels(app.UIAxes_conn_2,{'Corr'; 'Par-Corr'})
            title(app.UIAxes_conn_2,'Correlation A-C')
            xlabel(app.UIAxes_conn_2,[])
            ylabel(app.UIAxes_conn_2,[])
              
            cla(app.UIAxes_conn_3,'reset')
            bar(app.UIAxes_conn_3, 1, cor_BC,'FaceColor',[0 0 0])
            hold(app.UIAxes_conn_3,'on')
            bar(app.UIAxes_conn_3, 2, pcor_BC,'FaceColor',[0.6 0.6 0.6])
            plot(app.UIAxes_conn_3,[0 3],[0 0],'Color',[0.5 0.5 0.5])
            xlim(app.UIAxes_conn_3,[-0.5 3.5]);
            ylim(app.UIAxes_conn_3,[-1 1]);
            xticks(app.UIAxes_conn_3,[1 2])
            xticklabels(app.UIAxes_conn_3,{'Corr'; 'Par-Corr'})
            title(app.UIAxes_conn_3,'Correlation B-C')
            xlabel(app.UIAxes_conn_3,[])
            ylabel(app.UIAxes_conn_3,[])
        end
    
        function compute_xcorrelation(app)
            xA = app.signals.xA(1:1000);
            xB = app.signals.xB(1:1000);
            xC = app.signals.xC(1:1000);
            Xcor_AB = xcorr(xA,xB,'coef');
            Xcor_AC = xcorr(xA,xC,'coef');
            Xcor_BC = xcorr(xB,xC,'coef');
            t=linspace(-1000,1000,length(Xcor_AB));
            
            cla(app.UIAxes_conn_1,'reset')
            %hold(app.UIAxes_conn_1,'off')
            plot(app.UIAxes_conn_1, t, Xcor_AB)
            hold(app.UIAxes_conn_1,'on')
            plot(app.UIAxes_conn_1,[-1000 1000],[0 0],'Color',[0.5 0.5 0.5])
            plot(app.UIAxes_conn_1,[0 0],[-1 1],'Color',[0.5 0.5 0.5])
            xlim(app.UIAxes_conn_1,[-250 250]);
            ylim(app.UIAxes_conn_1,[-1 1]);
            title(app.UIAxes_conn_1,'Cross-Correlation A-B')
            xlabel(app.UIAxes_conn_1,'Time (ms)')
            ylabel(app.UIAxes_conn_1,[])
            
            cla(app.UIAxes_conn_2,'reset')
            %hold(app.UIAxes_conn_2,'off')
            plot(app.UIAxes_conn_2, t, Xcor_AC)
            hold(app.UIAxes_conn_2,'on')
            plot(app.UIAxes_conn_2,[-1000 1000],[0 0],'Color',[0.5 0.5 0.5])
            plot(app.UIAxes_conn_2,[0 0],[-1 1],'Color',[0.5 0.5 0.5])
            xlim(app.UIAxes_conn_2,[-250 250]);
            ylim(app.UIAxes_conn_2,[-1 1]);
            title(app.UIAxes_conn_2,'Cross-Correlation A-C')
            xlabel(app.UIAxes_conn_2,'Time (ms)')
            ylabel(app.UIAxes_conn_2,[])
             
            cla(app.UIAxes_conn_3,'reset')
            %hold(app.UIAxes_conn_3,'off')
            plot(app.UIAxes_conn_3, t, Xcor_BC)
            hold(app.UIAxes_conn_3,'on')
            plot(app.UIAxes_conn_3,[-1000 1000],[0 0],'Color',[0.5 0.5 0.5])
            plot(app.UIAxes_conn_3,[0 0],[-1 1],'Color',[0.5 0.5 0.5])
            xlim(app.UIAxes_conn_3,[-250 250]);
            ylim(app.UIAxes_conn_3,[-1 1]);
            title(app.UIAxes_conn_3,'Cross-Correlation B-C')
            xlabel(app.UIAxes_conn_3,'Time (ms)')
            ylabel(app.UIAxes_conn_3,[])
        end
    
        function compute_cohere(app)
            xA = app.signals.xA;
            xB = app.signals.xB;
            xC = app.signals.xC;
            Coh_AB = mscohere(xA,xB,1024,512);
            Coh_AC = mscohere(xA,xC,1024,512);
            Coh_BC = mscohere(xB,xC,1024,512);
            f=linspace(0,500,length(Coh_AB));
            
            cla(app.UIAxes_conn_1,'reset')
            plot(app.UIAxes_conn_1, f, Coh_AB)
            xlim(app.UIAxes_conn_1,[0 100]);
            ylim(app.UIAxes_conn_1,[0 1]);
            title(app.UIAxes_conn_1,'Coherence A-B')
            xlabel(app.UIAxes_conn_1,'Frequency (Hz)')
            ylabel(app.UIAxes_conn_1,[])
            
            cla(app.UIAxes_conn_2,'reset')
            plot(app.UIAxes_conn_2, f, Coh_AC)
            xlim(app.UIAxes_conn_2,[0 100]);
            ylim(app.UIAxes_conn_2,[0 1]);
            title(app.UIAxes_conn_2,'Coherence A-C')
            xlabel(app.UIAxes_conn_2,'Frequency (Hz)')
            ylabel(app.UIAxes_conn_2,[])
            
            cla(app.UIAxes_conn_3,'reset')
            plot(app.UIAxes_conn_3, f, Coh_BC)
            xlim(app.UIAxes_conn_3,[0 100]);
            ylim(app.UIAxes_conn_3,[0 1]);
            title(app.UIAxes_conn_3,'Coherence B-C')
            xlabel(app.UIAxes_conn_3,'Frequency (Hz)')
            ylabel(app.UIAxes_conn_3,[])
        end
    
        function compute_granger(app)
            x(1,:) = app.signals.xA;
            x(2,:) = app.signals.xB;
            x(3,:) = app.signals.xC;
            
            % Model_order
            % [bic,aic] = cca_find_model_order(x,1,50);
            % Use the highest delay + 1
            aic = max([app.AB_del_Spinner.Value app.BA_del_Spinner.Value...
                       app.AC_del_Spinner.Value app.CA_del_Spinner.Value...
                       app.BC_del_Spinner.Value app.CB_del_Spinner.Value]) + 5;
            
            % Pair-wise Granger           
            if strcmp(app.ConnectivitymethodDropDown.Value,'Granger Causality')
                % Granger Causality
                clear GC 
                ret = cca_granger_regress(x([1 2],:),aic); GC_aux = ret.gc;
                GC(1,2) = GC_aux(1,2); GC(2,1) = GC_aux(2,1);
                ret = cca_granger_regress(x([1 3],:),aic); GC_aux = ret.gc;
                GC(1,3) = GC_aux(1,2); GC(3,1) = GC_aux(2,1);
                ret = cca_granger_regress(x([2 3],:),aic); GC_aux = ret.gc;
                GC(2,3) = GC_aux(1,2); GC(3,2) = GC_aux(2,1);
                
            end
            
            % "Partial" Granger           
            if strcmp(app.ConnectivitymethodDropDown.Value,'Partial Granger Causality')
                % Granger Causality
                ret = cca_granger_regress(x,aic);
                PR = cca_findsignificance(ret,0.01,1);
                GC = ret.gc;
                GC = GC.*PR;
            end
            
            cla(app.UIAxes_conn_1,'reset')
            bar(app.UIAxes_conn_1, 1, GC(2,1),'FaceColor',[0.1 0.1 0.1])
            hold(app.UIAxes_conn_1,'on')
            bar(app.UIAxes_conn_1, 2, GC(1,2),'FaceColor',[0.1 0.1 0.1])
            xlim(app.UIAxes_conn_1,[-0.5 3.5]);
            ylim(app.UIAxes_conn_1,[0 1]);
            xticks(app.UIAxes_conn_1,[1 2])
            xticklabels(app.UIAxes_conn_1,{'A \rightarrow B'; 'B \rightarrow A'})
            title(app.UIAxes_conn_1,'Granger Causality A-B')
            xlabel(app.UIAxes_conn_1,[])
            ylabel(app.UIAxes_conn_1,[])
            
            cla(app.UIAxes_conn_2,'reset')
            bar(app.UIAxes_conn_2, 1, GC(3,1),'FaceColor',[0.1 0.1 0.1])
            hold(app.UIAxes_conn_2,'on')
            bar(app.UIAxes_conn_2, 2, GC(1,3),'FaceColor',[0.1 0.1 0.1])
            xlim(app.UIAxes_conn_2,[-0.5 3.5]);
            ylim(app.UIAxes_conn_2,[0 1]);
            xticks(app.UIAxes_conn_2,[1 2])
            xticklabels(app.UIAxes_conn_2,{'A \rightarrow C'; 'C \rightarrow A'})
            title(app.UIAxes_conn_2,'Granger Causality A-C')
            xlabel(app.UIAxes_conn_2,[])
            ylabel(app.UIAxes_conn_2,[])
               
            cla(app.UIAxes_conn_3,'reset')
            bar(app.UIAxes_conn_3, 1, GC(3,2),'FaceColor',[0.1 0.1 0.1])
            hold(app.UIAxes_conn_3,'on')
            bar(app.UIAxes_conn_3, 2, GC(2,3),'FaceColor',[0.1 0.1 0.1])
            xlim(app.UIAxes_conn_3,[-0.5 3.5]);
            ylim(app.UIAxes_conn_3,[0 1]);
            xticks(app.UIAxes_conn_3,[1 2])
            xticklabels(app.UIAxes_conn_3,{'B \rightarrow C'; 'C \rightarrow B'})
            title(app.UIAxes_conn_3,'Granger Causality B-C')
            xlabel(app.UIAxes_conn_3,[])
            ylabel(app.UIAxes_conn_3,[])    
            
            if strcmp(app.ConnectivitymethodDropDown.Value,'Partial Granger Causality')
                title(app.UIAxes_conn_1,'Granger Causality A-B (part C)')
                title(app.UIAxes_conn_2,'Granger Causality A-C (part B)')
                title(app.UIAxes_conn_3,'Granger Causality B-C (part A)')
            end
        end
    
    function compute_spectral_granger(app)
            x(1,:) = app.signals.xA;
            x(2,:) = app.signals.xB;
            x(3,:) = app.signals.xC;
            
            % Model_order
            % [bic,aic] = cca_find_model_order(x,1,50);
            % Use the highest delay + 1
            aic = max([app.AB_del_Spinner.Value app.BA_del_Spinner.Value...
                       app.AC_del_Spinner.Value app.CA_del_Spinner.Value...
                       app.BC_del_Spinner.Value app.CB_del_Spinner.Value]) + 5;
            Fs      =   1000;
            freqs   =   1:100;
                    
%             % Pair-wise Granger    
%             if strcmp(app.ConnectivitymethodDropDown.Value,'Spectral GC')
%                 clear GW 
%                 GW_aux = cca_pwcausal(x([1 2],:),1,length(x),aic,Fs,freqs,0);
%                 GW(1,2,:) = GW_aux(1,2,:); GW(2,1,:) = GW_aux(2,1,:);
%                 GW_aux = cca_pwcausal(x([1 3],:),1,length(x),aic,Fs,freqs,0);
%                 GW(1,3,:) = GW_aux(1,2,:); GW(3,1,:) = GW_aux(2,1,:);
%                 GW_aux = cca_pwcausal(x([2 3],:),1,length(x),aic,Fs,freqs,0);
%                 GW(2,3,:) = GW_aux(1,2,:); GW(3,2,:) = GW_aux(2,1,:);
%             end
            
            % Granger Causality
            if strcmp(app.ConnectivitymethodDropDown.Value,'Spectral Granger Causality')
                GW = cca_pwcausal(x,1,length(x),aic,Fs,freqs,0);
            end
            
            cla(app.UIAxes_conn_1,'reset')
            MAB = max(GW(2,1,:))*1.25; MBA = max(GW(1,2,:))*1.25;
            Maxis = max([1 MAB MBA]);
            plot(app.UIAxes_conn_1, freqs, squeeze(GW(2,1,:)),...
                'LineWidth',1,'Color',[0 0 0],'DisplayName','A \rightarrow B')
            hold(app.UIAxes_conn_1,'on')
            plot(app.UIAxes_conn_1, freqs, squeeze(GW(1,2,:)),...
                'LineWidth',1,'Color',[0.5 0.5 0.5],'DisplayName','B \rightarrow A')
            legend(app.UIAxes_conn_1)
            xlim(app.UIAxes_conn_1,[0 100]);
            ylim(app.UIAxes_conn_1,[0 Maxis]);
            title(app.UIAxes_conn_1,'Spectral Granger Causality A-B')
            xlabel(app.UIAxes_conn_1,'Frequency (Hz)')
            ylabel(app.UIAxes_conn_1,[])
            
            cla(app.UIAxes_conn_2,'reset')
            MAB = max(GW(3,1,:))*1.25; MBA = max(GW(1,3,:))*1.25;
            Maxis = max([1 MAB MBA]);
            plot(app.UIAxes_conn_2, freqs, squeeze(GW(3,1,:)),...
                'LineWidth',1,'Color',[0 0 0],'DisplayName','A \rightarrow C')
            hold(app.UIAxes_conn_2,'on')
            plot(app.UIAxes_conn_2, freqs, squeeze(GW(1,3,:)),...
                'LineWidth',1,'Color',[0.5 0.5 0.5],'DisplayName','C \rightarrow A')
            legend(app.UIAxes_conn_2)
            xlim(app.UIAxes_conn_2,[0 100]);
            ylim(app.UIAxes_conn_2,[0 Maxis]);
            title(app.UIAxes_conn_2,'Spectral Granger Causality A-C')
            xlabel(app.UIAxes_conn_2,'Frequency (Hz)')
            ylabel(app.UIAxes_conn_2,[])
                
            cla(app.UIAxes_conn_3,'reset')
            MAB = max(GW(3,2,:))*1.25; MBA = max(GW(2,3,:))*1.25;
            Maxis = max([1 MAB MBA]);
            plot(app.UIAxes_conn_3, freqs, squeeze(GW(3,2,:)),...
                'LineWidth',1,'Color',[0 0 0],'DisplayName','B \rightarrow C')
            hold(app.UIAxes_conn_3,'on')
            plot(app.UIAxes_conn_3, freqs, squeeze(GW(2,3,:)),...
                'LineWidth',1,'Color',[0.5 0.5 0.5],'DisplayName','C \rightarrow B')
            legend(app.UIAxes_conn_3)
            xlim(app.UIAxes_conn_3,[0 100]);
            ylim(app.UIAxes_conn_3,[0 Maxis]);
            title(app.UIAxes_conn_3,'Spectral Granger Causality B-C')
            xlabel(app.UIAxes_conn_3,'Frequency (Hz)')
            ylabel(app.UIAxes_conn_3,[]) 
             
%             if strcmp(app.ConnectivitymethodDropDown.Value,'Partial Spectral GC')
%                 title(app.UIAxes_conn_1,'Spectral Granger Causality A-B (part C)')
%                 title(app.UIAxes_conn_2,'Spectral Granger Causality A-C (part B)')
%                 title(app.UIAxes_conn_3,'Spectral Granger Causality B-C (part A)')
%             end
        end

    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.flag_load=0;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Callback function
        function PlotconnectivityButtonPushed(app, event)
            %conn=[0 1 0;1 0 2;0 0 0.1];
            %conn=zeros(3);
            %if app.AconBCheckBox.Value, conn(1,2)=1; end
            %if app.BconACheckBox.Value, conn(2,1)=1; end
            %if app.AconCCheckBox.Value, conn(1,3)=1; end
            %if app.CconACheckBox.Value, conn(3,1)=1; end
            %if app.BconCCheckBox.Value, conn(2,3)=1; end
            %if app.CconBCheckBox.Value, conn(3,2)=1; end
            plot_conectivity(app);
        end

        % Value changed function: AB_int_Slider
        function AB_int_SliderValueChanged(app, event)
            %value = app.AB_int_Slider.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Value changed function: BA_int_Slider
        function BA_int_SliderValueChanged(app, event)
            %value = app.BA_int_Slider.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Value changed function: AC_int_Slider
        function AC_int_SliderValueChanged(app, event)
            %value = app.AC_int_Slider.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Value changed function: CA_int_Slider
        function CA_int_SliderValueChanged(app, event)
            %value = app.CA_int_Slider.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Value changed function: BC_int_Slider
        function BC_int_SliderValueChanged(app, event)
            %value = app.BC_int_Slider.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Value changed function: CB_int_Slider
        function CB_int_SliderValueChanged(app, event)
            %value = app.CB_int_Slider.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Value changed function: NoiseA
        function NoiseAValueChanged(app, event)
            %value = app.NoiseA.Value;
            generate_signals(app);
        end

        % Value changed function: FreqA
        function FreqAValueChanged(app, event)
            %value = app.FreqA.Value;
            %Round frequency value (1:5:100)
            freq=app.FreqA.Value;
            freq=(round(freq/5)*5);
            if freq==0, freq=1; end
            app.FreqA.Value=freq;
            generate_signals(app);
        end

        % Value changed function: NoiseB
        function NoiseBValueChanged(app, event)
            %value = app.NoiseB.Value;
            generate_signals(app);
        end

        % Value changed function: FreqB
        function FreqBValueChanged(app, event)
            %value = app.FreqB.Value;
            %Round frequency value (1:5:100)
            freq=app.FreqB.Value;
            freq=(round(freq/5)*5);
            if freq==0, freq=1; end
            app.FreqB.Value=freq;
            generate_signals(app);
        end

        % Value changed function: NoiseC
        function NoiseCValueChanged(app, event)
            %value = app.NoiseC.Value;
            generate_signals(app);
        end

        % Value changed function: FreqC
        function FreqCValueChanged(app, event)
            %value = app.FreqC.Value;
            %Round frequency value (1:5:100)
            freq=app.FreqC.Value;
            freq=(round(freq/5)*5);
            if freq==0, freq=1; end
            app.FreqC.Value=freq;
            generate_signals(app);
        end

        % Value changed function: PhaseA
        function PhaseAValueChanged(app, event)
            %value = app.PhaseA.Value;
            %Round phase value (0:20:360)
            ph=app.PhaseA.Value;
            ph=(round(ph/20)*20);
            app.PhaseA.Value=ph;
            generate_signals(app);
        end

        % Value changed function: PhaseB
        function PhaseBValueChanged(app, event)
            %value = app.PhaseB.Value;
            %Round phase value (0:20:360)
            ph=app.PhaseB.Value;
            ph=(round(ph/20)*20);
            app.PhaseB.Value=ph;
            generate_signals(app);
        end

        % Value changed function: PhaseC
        function PhaseCValueChanged(app, event)
            %value = app.PhaseC.Value;
            %Round phase value (0:20:360)
            ph=app.PhaseC.Value;
            ph=(round(ph/20)*20);
            app.PhaseC.Value=ph;
            generate_signals(app);
        end

        % Value changed function: ConnectivitymethodDropDown
        function ConnectivitymethodDropDownValueChanged(app, event)
            %value = app.ConnectivitymethodDropDown.Value;
            %generate_signals(app);
            compute_connectivity(app);
        end

        % Value changed function: AB_del_Spinner, AC_del_Spinner, 
        % BA_del_Spinner, BC_del_Spinner, CA_del_Spinner, 
        % CB_del_Spinner
        function del_SpinnerValueChanged(app, event)
            %value = app.AB_del_Spinner.Value;
            plot_conectivity(app);
            generate_signals(app);
        end

        % Button pushed function: SAVEButton
        function save_data(app, event)
            filename = app.ConnectivitymethodDropDown.Value;
            if strcmp(filename,'-'), filename = []; end
            [name,path] = uiputfile('*.*','Save files',filename);
            if name~=0
                filedir = [path name];
                fig = figure();
                ax = subplot(2,3,[1 2]);
                copyUIAxes(app.UIAxes_signal, ax);
                ax = subplot(2,3,3);
                copyUIAxes(app.UIAxes_PS, ax);
                ax = subplot(2,3,4);
                copyUIAxes(app.UIAxes_conn_1, ax);
                ax = subplot(2,3,5);
                copyUIAxes(app.UIAxes_conn_2, ax);
                ax = subplot(2,3,6);
                copyUIAxes(app.UIAxes_conn_3, ax);
                fig.Position = [300 300 900 500];
                saveas(fig,[filedir '.jpg'])
                close(fig)
                
                x(1,:) = app.signals.xA;
                x(2,:) = app.signals.xB;
                x(3,:) = app.signals.xC;
                save(filedir,'x')
            end
        end

        % Button pushed function: LOADButton
        function load_data(app, event)
            [file,dir]=uigetfile('*.mat','Select file');
            if file~=0
                dirfile = [dir file];
                data=load(dirfile);
                if ~isfield(data,'x')
                    errordlg('Data must be in a variable "x"'), return
                end
                if size(data.x,1)~=2 && size(data.x,1)~=3
                    errordlg('Data in "x" must contain 2 or 3 rows'), return
                end
                
                % I need Fs
                   
                % Disable parameters
                restart_app(app, event)
                %%% Frequency
                app.FreqA.Enable = 0;
                app.FreqB.Enable = 0;
                app.FreqC.Enable = 0;
                %%% Delay
                app.PhaseA.Enable = 0;
                app.PhaseB.Enable = 0;
                app.PhaseC.Enable = 0;
                %%% Noise
                app.NoiseA.Enable = 0;
                app.NoiseB.Enable = 0;
                app.NoiseC.Enable = 0;
                %%% Connectivity
                app.AB_int_Slider.Enable = 0;
                app.BA_int_Slider.Enable = 0;
                app.AC_int_Slider.Enable = 0;
                app.CA_int_Slider.Enable = 0;
                app.BC_int_Slider.Enable = 0;
                app.CB_int_Slider.Enable = 0; 
                %%% Delay
                app.AB_del_Spinner.Enable = 0;
                app.BA_del_Spinner.Enable = 0;
                app.AC_del_Spinner.Enable = 0;
                app.CA_del_Spinner.Enable = 0;
                app.BC_del_Spinner.Enable = 0;
                app.CB_del_Spinner.Enable = 0;
                
                %% Load data
                app.flag_load=1;
                
                % Load app.signals
                app.signals.xA = data.x(1,:);
                app.signals.xB = data.x(2,:);
                if size(data.x,1)==2 % Only two app.signals
                    app.signals.xC = zeros(1,size(data.x,2));
                else
                    app.signals.xC = data.x(3,:);
                end
                
                plot_signals(app)
            end
        end

        % Button pushed function: RESTARTButton
        function restart_app(app, event)
            % Restart default values
            app.flag_load=0;
            
            %%% Frequency
            app.FreqA.Value = 10;
            app.FreqB.Value = 10;
            app.FreqC.Value = 10;
            app.FreqA.Enable = 1;
            app.FreqB.Enable = 1;
            app.FreqC.Enable = 1;
            
            %%% Delay
            app.PhaseA.Value = 0;
            app.PhaseB.Value = 0;
            app.PhaseC.Value = 0;
            app.PhaseA.Enable = 1;
            app.PhaseB.Enable = 1;
            app.PhaseC.Enable = 1;
            
            %%% Noise
            app.NoiseA.Value = 5;
            app.NoiseB.Value = 5;
            app.NoiseC.Value = 5;
            app.NoiseA.Enable = 1;
            app.NoiseB.Enable = 1;
            app.NoiseC.Enable = 1;
            
            %%% Connectivity
            app.AB_int_Slider.Value = 0;
            app.BA_int_Slider.Value = 0;
            app.AC_int_Slider.Value = 0;
            app.CA_int_Slider.Value = 0;
            app.BC_int_Slider.Value = 0;
            app.CB_int_Slider.Value = 0;
            app.AB_int_Slider.Enable = 1;
            app.BA_int_Slider.Enable = 1;
            app.AC_int_Slider.Enable = 1;
            app.CA_int_Slider.Enable = 1;
            app.BC_int_Slider.Enable = 1;
            app.CB_int_Slider.Enable = 1;
            
            %%% Delay
            app.AB_del_Spinner.Value = 0;
            app.BA_del_Spinner.Value = 0;
            app.AC_del_Spinner.Value = 0;
            app.CA_del_Spinner.Value = 0;
            app.BC_del_Spinner.Value = 0;
            app.CB_del_Spinner.Value = 0;
            app.AB_del_Spinner.Enable = 1;
            app.BA_del_Spinner.Enable = 1;
            app.AC_del_Spinner.Enable = 1;
            app.CA_del_Spinner.Enable = 1;
            app.BC_del_Spinner.Enable = 1;
            app.CB_del_Spinner.Enable = 1;
            
            %%% Method
            app.ConnectivitymethodDropDown.Value = '-';
            
            plot_conectivity(app);
            generate_signals(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Conectividad = uifigure;
            app.Conectividad.Name = 'UI Figure';
            app.UIAxes_graph = uiaxes(app.Conectividad);
            title(app.UIAxes_graph, 'Title')
            xlabel(app.UIAxes_graph, 'X')
            ylabel(app.UIAxes_graph, 'Y')
            app.AB_int_Slider = uislider(app.Conectividad);
            app.AB_int_Slider.MajorTickLabels = {'', '', '', '', ''};
            app.AB_int_Slider.ValueChangedFcn = createCallbackFcn(app, @AB_int_SliderValueChanged, true);
            app.FromAtoBLabel = uilabel(app.Conectividad);
            app.FromAtoBLabel.Text = 'From A to B';
            app.BA_int_Slider = uislider(app.Conectividad);
            app.BA_int_Slider.MajorTickLabels = {'', '', '', '', ''};
            app.BA_int_Slider.ValueChangedFcn = createCallbackFcn(app, @BA_int_SliderValueChanged, true);
            app.FromBtoALabel = uilabel(app.Conectividad);
            app.FromBtoALabel.Text = 'From B to A';
            app.AC_int_Slider = uislider(app.Conectividad);
            app.AC_int_Slider.MajorTickLabels = {'', '', '', '', ''};
            app.AC_int_Slider.ValueChangedFcn = createCallbackFcn(app, @AC_int_SliderValueChanged, true);
            app.FromAtoCLabel = uilabel(app.Conectividad);
            app.FromAtoCLabel.Text = 'From A to C';
            app.CA_int_Slider = uislider(app.Conectividad);
            app.CA_int_Slider.MajorTickLabels = {'', '', '', '', ''};
            app.CA_int_Slider.ValueChangedFcn = createCallbackFcn(app, @CA_int_SliderValueChanged, true);
            app.FromCtoALabel = uilabel(app.Conectividad);
            app.FromCtoALabel.Text = 'From C to A';
            app.BC_int_Slider = uislider(app.Conectividad);
            app.BC_int_Slider.MajorTickLabels = {'', '', '', '', ''};
            app.BC_int_Slider.ValueChangedFcn = createCallbackFcn(app, @BC_int_SliderValueChanged, true);
            app.FromBtoCLabel = uilabel(app.Conectividad);
            app.FromBtoCLabel.Text = 'From B to C';
            app.CB_int_Slider = uislider(app.Conectividad);
            app.CB_int_Slider.ValueChangedFcn = createCallbackFcn(app, @CB_int_SliderValueChanged, true);
            app.FromCtoBLabel = uilabel(app.Conectividad);
            app.FromCtoBLabel.Text = 'From C to B';
            app.IntensityauLabel = uilabel(app.Conectividad);
            app.IntensityauLabel.Text = 'Intensity (a.u.)';
            app.DelaysamplesLabel = uilabel(app.Conectividad);
            app.DelaysamplesLabel.Text = 'Delay (samples)';
            app.UIAxes_signal = uiaxes(app.Conectividad);
            title(app.UIAxes_signal, 'Title')
            xlabel(app.UIAxes_signal, 'X')
            ylabel(app.UIAxes_signal, 'Y')
            app.NoiseA = uislider(app.Conectividad);
            app.NoiseA.ValueChangedFcn = createCallbackFcn(app, @NoiseAValueChanged, true);
            app.NoiseA.Value = 5;
            app.FreqA = uislider(app.Conectividad);
            app.FreqA.Limits = [1 100];
            app.FreqA.MajorTicks = [1 25 50 75 100];
            app.FreqA.ValueChangedFcn = createCallbackFcn(app, @FreqAValueChanged, true);
            app.FreqA.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.FreqA.Value = 10;
            app.ALabel = uilabel(app.Conectividad);
            app.ALabel.Text = 'A';
            app.NoiseB = uislider(app.Conectividad);
            app.NoiseB.ValueChangedFcn = createCallbackFcn(app, @NoiseBValueChanged, true);
            app.NoiseB.Value = 5;
            app.FreqB = uislider(app.Conectividad);
            app.FreqB.Limits = [1 100];
            app.FreqB.MajorTicks = [1 25 50 75 100];
            app.FreqB.ValueChangedFcn = createCallbackFcn(app, @FreqBValueChanged, true);
            app.FreqB.MinorTicks = [5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.FreqB.Value = 10;
            app.BLabel = uilabel(app.Conectividad);
            app.BLabel.Text = 'B';
            app.NoiseC = uislider(app.Conectividad);
            app.NoiseC.ValueChangedFcn = createCallbackFcn(app, @NoiseCValueChanged, true);
            app.NoiseC.Value = 5;
            app.FreqC = uislider(app.Conectividad);
            app.FreqC.Limits = [1 100];
            app.FreqC.MajorTicks = [1 25 50 75 100];
            app.FreqC.ValueChangedFcn = createCallbackFcn(app, @FreqCValueChanged, true);
            app.FreqC.MinorTicks = [5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.FreqC.Value = 10;
            app.CLabel = uilabel(app.Conectividad);
            app.CLabel.Text = 'C';
            app.FrequencyHzLabel = uilabel(app.Conectividad);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.VariabilityLabel = uilabel(app.Conectividad);
            app.VariabilityLabel.Text = 'Variability';
            app.PhaseA = uislider(app.Conectividad);
            app.PhaseA.Limits = [0 360];
            app.PhaseA.ValueChangedFcn = createCallbackFcn(app, @PhaseAValueChanged, true);
            app.PhaseLabel = uilabel(app.Conectividad);
            app.PhaseLabel.Text = 'Phase (º)';
            app.PhaseB = uislider(app.Conectividad);
            app.PhaseB.Limits = [0 360];
            app.PhaseB.ValueChangedFcn = createCallbackFcn(app, @PhaseBValueChanged, true);
            app.PhaseC = uislider(app.Conectividad);
            app.PhaseC.Limits = [0 360];
            app.PhaseC.ValueChangedFcn = createCallbackFcn(app, @PhaseCValueChanged, true);
            app.UIAxes_PS = uiaxes(app.Conectividad);
            title(app.UIAxes_PS, 'Title')
            xlabel(app.UIAxes_PS, 'X')
            ylabel(app.UIAxes_PS, 'Y')
            app.ConnectivitymethodLabel = uilabel(app.Conectividad);
            app.ConnectivitymethodLabel.Text = {'Connectivity '; 'method'};
            app.ConnectivitymethodDropDown = uidropdown(app.Conectividad);
            app.ConnectivitymethodDropDown.Items = {'-', 'Correlation', 'Cross-Correlation', 'Coherence', 'Granger Causality', 'Partial Granger Causality', 'Spectral Granger Causality'};
            app.ConnectivitymethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectivitymethodDropDownValueChanged, true);
            app.ConnectivitymethodDropDown.Value = '-';
            app.UIAxes_conn_1 = uiaxes(app.Conectividad);
            title(app.UIAxes_conn_1, 'Title')
            xlabel(app.UIAxes_conn_1, 'X')
            ylabel(app.UIAxes_conn_1, 'Y')
            app.UIAxes_conn_2 = uiaxes(app.Conectividad);
            title(app.UIAxes_conn_2, 'Title')
            xlabel(app.UIAxes_conn_2, 'X')
            ylabel(app.UIAxes_conn_2, 'Y')
            app.UIAxes_conn_3 = uiaxes(app.Conectividad);
            title(app.UIAxes_conn_3, 'Title')
            xlabel(app.UIAxes_conn_3, 'X')
            ylabel(app.UIAxes_conn_3, 'Y')
            app.AB_del_Spinner = uispinner(app.Conectividad);
            app.AB_del_Spinner.Limits = [0 Inf];
            app.AB_del_Spinner.ValueChangedFcn = createCallbackFcn(app, @del_SpinnerValueChanged, true);
            app.BA_del_Spinner = uispinner(app.Conectividad);
            app.BA_del_Spinner.Limits = [0 Inf];
            app.BA_del_Spinner.ValueChangedFcn = createCallbackFcn(app, @del_SpinnerValueChanged, true);
            app.AC_del_Spinner = uispinner(app.Conectividad);
            app.AC_del_Spinner.Limits = [0 Inf];
            app.AC_del_Spinner.ValueChangedFcn = createCallbackFcn(app, @del_SpinnerValueChanged, true);
            app.CA_del_Spinner = uispinner(app.Conectividad);
            app.CA_del_Spinner.Limits = [0 Inf];
            app.CA_del_Spinner.ValueChangedFcn = createCallbackFcn(app, @del_SpinnerValueChanged, true);
            app.BC_del_Spinner = uispinner(app.Conectividad);
            app.BC_del_Spinner.Limits = [0 Inf];
            app.BC_del_Spinner.ValueChangedFcn = createCallbackFcn(app, @del_SpinnerValueChanged, true);
            app.CB_del_Spinner = uispinner(app.Conectividad);
            app.CB_del_Spinner.Limits = [0 Inf];
            app.CB_del_Spinner.ValueChangedFcn = createCallbackFcn(app, @del_SpinnerValueChanged, true);
            app.LOADButton = uibutton(app.Conectividad, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
            app.LOADButton.Text = 'LOAD';
            app.SAVEButton = uibutton(app.Conectividad, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @save_data, true);
            app.SAVEButton.Text = 'SAVE';
            app.RESTARTButton = uibutton(app.Conectividad, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @restart_app, true);
            app.RESTARTButton.Text = 'RESTART';
        end
    end

    methods (Access = public)

        % Construct app
        function app = conectividad

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Conectividad)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Conectividad)
        end
    end
end